classdef drta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        file_name          matlab.ui.control.TextArea
        trial_amt          matlab.ui.control.NumericEditField
        Trial_Label        matlab.ui.control.Label
        dt_amt             matlab.ui.control.NumericEditField
        dt_Label           matlab.ui.control.Label
        type_DropDown      matlab.ui.control.DropDown
        ProgressReadout    matlab.ui.control.TextArea
        Load_Button        matlab.ui.control.StateButton
        copyright_Label    matlab.ui.control.Label
        SelectFile_Button  matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        drta_handles = struct();% Description
        drta_Plot % Description
        OutputText % This is the text that is displayed on the GUI
        FlagAlpha %  Flag is used to indicate the first call to readout
    end
    
    methods (Access = private)
        
        function open_rhd_Callback(app)
%             open_rhd_Callback(hObject, eventdata, handles)
            % hObject    handle to open_rhd (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            FileName=app.drta_handles.p.FileName;
            app.drta_handles.drtaWhichFile = FileName;
            % set(app.drta_handles.drtaWhichFile,'String',FileName);
            
            % if exist([app.drta_handles.p.fullName(1:end-3),'mat'],'file')==2
            %     load([app.drta_handles.p.fullName(1:end-3),'mat']);
            %     try
            %         app.drta_handles.draq_p=params;
            %         app.drta_handles.draq_d=data;
            %     catch
            %         app.drta_handles.draq_p=draq_p;
            %         app.drta_handles.draq_d=draq_d;
            %     end
            % else
            %     %Setup the matlab header file
            %     [app.drta_handles.draq_p,app.drta_handles.draq_d]=drta_read_Intan_RHD2000_header(app.drta_handles.p.fullName,app.drta_handles.p.which_protocol,app.drta_handles);
            % end
            app.drta_handles.GUIs.drtaLoad = app;
            %Setup the matlab header file
            [app.drta_handles.draq_p,app.drta_handles.draq_d]=drta_read_Intan_RHD2000_header(app.drta_handles);
             
            app.drta_handles.draq_p.dgordra=3;  
            
            
            scaling = app.drta_handles.draq_p.scaling;
            offset = app.drta_handles.draq_p.offset;
            
            app.drta_handles.draq_p.no_spike_ch=16;
            app.drta_handles.draq_p.daq_gain=1;
            app.drta_handles.draq_p.prev_ylim(1:17)=4000;
            app.drta_handles.draq_p.no_chans=22;
            app.drta_handles.draq_p.acquire_display_start=0;
            app.drta_handles.draq_p.inp_max=10;
            % app.drta_handles.p.trialNo=2;
            % app.drta_handles.p.low_filter=1000;
            % app.drta_handles.p.high_filter=5000;
            % app.drta_handles.p.whichPlot=1;
            % app.drta_handles.p.lastTrace=-1;
            % app.drta_handles.p.exclude_secs=2;
            % app.drta_handles.p.set2p5SD=0;
            % app.drta_handles.p.setm2p5SD=0;
            % app.drta_handles.p.setnxSD=0;
            % app.drta_handles.p.nxSD=2.5;
            % app.drta_handles.p.showLicks=1;
            % app.drta_handles.p.exc_sn=0;
            % app.drta_handles.p.read_entire_file=0;
            % app.drta_handles.p.setThr=0;
            % app.drta_handles.p.thrToSet=0;
            % app.drta_handles.p.which_protocol=1;


            if exist('drta_p','var')==1
                if isfield(drta_p,'doSubtract')
                    app.drta_handles.p.doSubtract=drta_p.doSubtract;
                    app.drta_handles.p.subtractCh=drta_p.subtractCh;
                else
                    app.drta_handles.p.doSubtract=0;
                    app.drta_handles.p.subtractCh=[18 18 18 18 18 18 18 18 18 18 18 18 18 18 18 18];
                end
            else
                app.drta_handles.p.doSubtract=0;
                app.drta_handles.p.subtractCh=[18 18 18 18 18 18 18 18 18 18 18 18 18 18 18 18];
            end
            
            for (ii=1:app.drta_handles.draq_p.no_spike_ch)
                try
                    v_max=(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain*app.drta_handles.draq_p.daq_gain/1000000);
                    v_min=-(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain*app.drta_handles.draq_p.daq_gain/1000000);
                    app.drta_handles.draq_p.nat_max(ii)=(v_max-offset)/scaling;
                    app.drta_handles.draq_p.nat_min(ii)=(v_min-offset)/scaling;
                    app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drta_handles.draq_p.no_spike_ch;
                    app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5);
                catch
                    v_max=(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain(ii)*app.drta_handles.draq_p.daq_gain/1000000);
                    v_min=-(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain(ii)*app.drta_handles.draq_p.daq_gain/1000000);
                    app.drta_handles.draq_p.nat_max(ii)=(v_max-offset)/scaling;
                    app.drta_handles.draq_p.nat_min(ii)=(v_min-offset)/scaling;
                    app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drta_handles.draq_p.no_spike_ch;
                    app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5);
                end
            
            end
            
            app.drta_handles.p.trial_ch_processed=ones(16,app.drta_handles.draq_d.noTrials);
            app.drta_handles.p.trial_allch_processed=ones(1,app.drta_handles.draq_d.noTrials);
            
            if (exist('drta_p')~=0)
                app.drta_handles.p.threshold=drta_p.threshold;
            
                if (isfield(drta_p,'ch_processed')~=0)
                    app.drta_handles.p.ch_processed=drta_p.ch_processed;
                end
                if (isfield(drta_p,'trial_ch_processed')~=0)
                    app.drta_handles.p.trial_ch_processed=drta_p.trial_ch_processed;
                end
                if (isfield(drta_p,'trial_allch_processed')~=0)
                    app.drta_handles.p.trial_allch_processed=drta_p.trial_allch_processed;
                end
                if (isfield(drta_p,'upper_limit')~=0)
                    app.drta_handles.p.upper_limit=drta_p.upper_limit;
                    app.drta_handles.p.lower_limit=drta_p.lower_limit;
                end
                if isfield(drta_p,'exc_sn')
                    app.drta_handles.p.exc_sn=drta_p.exc_sn;
            %         app.drta_handles.p.exc_sn_thr=drta_p.exc_sn_thr;
            %         app.drta_handles.p.exc_sn_ch=drta_p.exc_sn_ch;
                end
            end
            drtaBrowseTraces(app,app.drta_handles);
        end
        
        function drtaOpenDG_Callback(app)
%             drtaOpenDG_Callback(hObject, eventdata, app.drta_handles)
            % hObject    handle to drtaOpenDG (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % app.drta_handles    structure with app.drta_handles and user data (see GUIDATA)
            
            
            FileName=app.drta_handles.p.FileName;
            set(app.drta_handles.drtaWhichFile,'String',FileName);
            load([app.drta_handles.p.fullName(1:end-2),'mat']);
            try
                app.drta_handles.draq_p=params;
                app.drta_handles.draq_d=data;
            catch
                app.drta_handles.draq_p=draq_p;
                app.drta_handles.draq_d=draq_d;
            end
            
            
            app.drta_handles.draq_p.dgordra=2;
            % sz_t_trial=size(draq_d.t_trial);
            % sz_samp=size(draq_d.samplesPerTrial);
            % if (sz_t_trial(2)<data.noTrials)||(sz_samp(2)<data.noTrials)
            %    app.drta_handles.draq_d.noTrials=min([sz_t_trial(2) sz_samp(2)]); 
            % end
            %app.drta_handles.p.fid=fopen(app.drta_handles.p.fullName,'r');
            
            scaling = app.drta_handles.draq_p.scaling;
            offset = app.drta_handles.draq_p.offset;
            
            app.drta_handles.draq_p.no_spike_ch=16;
            app.drta_handles.draq_p.daq_gain=8;
            app.drta_handles.draq_p.prev_ylim(1:17)=4000;
            app.drta_handles.draq_p.no_chans=22;
            app.drta_handles.draq_p.acquire_display_start=0;
            app.drta_handles.draq_p.inp_max=10;
            % app.drta_handles.p.trialNo=2;
            % app.drta_handles.p.low_filter=1000;
            % app.drta_handles.p.high_filter=5000;
            % app.drta_handles.p.whichPlot=1;
            % app.drta_handles.p.lastTrace=-1;
            % app.drta_handles.p.exclude_secs=2;
            % app.drta_handles.p.set2p5SD=0;
            % app.drta_handles.p.setm2p5SD=0;
            % app.drta_handles.p.setnxSD=0;
            % app.drta_handles.p.nxSD=2.5;
            % app.drta_handles.p.showLicks=1;
            % app.drta_handles.p.exc_sn=0;
            % app.drta_handles.p.read_entire_file=0;
            % app.drta_handles.p.setThr=0;
            % app.drta_handles.p.thrToSet=0;


            if exist('drta_p')==1
                if isfield(drta_p,'doSubtract')
                    app.drta_handles.p.doSubtract=drta_p.doSubtract;
                    app.drta_handles.p.subtractCh=drta_p.subtractCh;
                else
                    app.drta_handles.p.doSubtract=0;
                    app.drta_handles.p.subtractCh=[18 18 18 18 18 18 18 18 18 18 18 18 18 18 18 18];
                end
            else
                app.drta_handles.p.doSubtract=0;
                app.drta_handles.p.subtractCh=[18 18 18 18 18 18 18 18 18 18 18 18 18 18 18 18];
            end
            
            for (ii=1:app.drta_handles.draq_p.no_spike_ch)
                try
                    v_max=(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain*app.drta_handles.draq_p.daq_gain/1000000);
                    v_min=-(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain*app.drta_handles.draq_p.daq_gain/1000000);
                    app.drta_handles.draq_p.nat_max(ii)=(v_max-offset)/scaling;
                    app.drta_handles.draq_p.nat_min(ii)=(v_min-offset)/scaling;
                    app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drta_handles.draq_p.no_spike_ch;
                    app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5);
                catch
                    v_max=(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain(ii)*app.drta_handles.draq_p.daq_gain/1000000);
                    v_min=-(app.drta_handles.draq_p.prev_ylim(ii)*app.drta_handles.draq_p.pre_gain(ii)*app.drta_handles.draq_p.daq_gain/1000000);
                    app.drta_handles.draq_p.nat_max(ii)=(v_max-offset)/scaling;
                    app.drta_handles.draq_p.nat_min(ii)=(v_min-offset)/scaling;
                    app.drta_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drta_handles.draq_p.no_spike_ch;
                    app.drta_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drta_handles.draq_p.no_spike_ch)*(ii-0.5);
                end
            
            end
            
            app.drta_handles.p.trial_ch_processed=ones(16,app.drta_handles.draq_d.noTrials);
            app.drta_handles.p.trial_allch_processed=ones(1,app.drta_handles.draq_d.noTrials);
            
            if (exist('drta_p')~=0)
                app.drta_handles.p.threshold=drta_p.threshold;
            
                if (isfield(drta_p,'ch_processed')~=0)
                    app.drta_handles.p.ch_processed=drta_p.ch_processed;
                end
                if (isfield(drta_p,'trial_ch_processed')~=0)
                    app.drta_handles.p.trial_ch_processed=drta_p.trial_ch_processed;
                end
                if (isfield(drta_p,'trial_allch_processed')~=0)
                    app.drta_handles.p.trial_allch_processed=drta_p.trial_allch_processed;
                end
                if (isfield(drta_p,'upper_limit')~=0)
                    app.drta_handles.p.upper_limit=drta_p.upper_limit;
                    app.drta_handles.p.lower_limit=drta_p.lower_limit;
                end
                if isfield(drta_p,'exc_sn')
                    app.drta_handles.p.exc_sn=drta_p.exc_sn;
            %         app.drta_handles.p.exc_sn_thr=drta_p.exc_sn_thr;
            %         app.drta_handles.p.exc_sn_ch=drta_p.exc_sn_ch;
                end
                
            end
            
            app.drta_handles.p.trialNo=1;
            
            %Read recordings
            if app.drta_handles.p.read_entire_file==1
                app.drta_handles.draq_d.data=drtaReadData(app.drta_handles);
            end
            
            %Find the threshold for the licks
            app.drta_handles.p.lick_th_frac=0.3;
            one_per=zeros(1,length(app.drta_handles.draq_d.t_trial));
            ninetynine_per=zeros(1,length(app.drta_handles.draq_d.t_trial));
            ii_from=floor((app.drta_handles.draq_p.acquire_display_start+app.drta_handles.p.start_display_time)...
                *app.drta_handles.draq_p.ActualRate+1);
            ii_to=floor((app.drta_handles.draq_p.acquire_display_start+app.drta_handles.p.start_display_time...
                +app.drta_handles.p.display_interval)*app.drta_handles.draq_p.ActualRate)-2000;
            ii=19; %These are licks
            
            old_trial=app.drta_handles.p.trialNo;
            for trialNo=1:length(app.drta_handles.draq_d.t_trial)-1
                app.drta_handles.p.trialNo=trialNo;
                
                data_this_trial=drtaGetTraceData(app.drta_handles);
            
                this_data=[];
                this_data=data_this_trial(:,ii);
                one_per(trialNo)=prctile(this_data(ii_from:ii_to),0.5);
                ninetynine_per(trialNo)=prctile(this_data(ii_from:ii_to),99.5);
            end
            app.drta_handles.p.trialNo=old_trial;
            app.drta_handles.p.exc_sn_thr=app.drta_handles.p.lick_th_frac*mean(ninetynine_per-one_per);
            
            app.drta_handles.p.lfp.maxLFP=4100;
            app.drta_handles.p.lfp.minLFP=10;
            
            % Update app.drta_handles structure
            guidata(hObject, app.drta_handles);
            
            
            %Open threshold window
            h=drtaThresholdSnips;
            app.drta_handles.w.drtaThresholdSnips=h;
            drtaUpdateAllapp.drta_handlespw(hObject,app.drta_handles);
            drtaThresholdSnips('updateapp.drta_handles',h,eventdata,app.drta_handles);
            
            %Open browse traces
            h=drtaBrowseTraces;
            app.drta_handles.w.drtaBrowseTraces=h;
            % Update app.drta_handles structure
            guidata(hObject, app.drta_handles);
            % Update app.drta_handles structure here and elsewhere
            drtaUpdateAllapp.drta_handlespw(hObject, app.drta_handles);
            drtaBrowseTraces('updateapp.drta_handles',h,eventdata,app.drta_handles);
            drtaBrowseTraces('updateBrowseTraces',h);

            
        end


%         function drtaBrowseDraPush_Callback(app, handles)
%             
%             h=drtaBrowseTraces;
%             handles.w.drtaBrowseTraces=h;
%             % Update handles structure
%             guidata(hObject, handles);
%             % Update handles structure here and elsewhere
%             drtaUpdateAllHandlespw(app, handles);
%             drtaBrowseTraces('updateHandles',h,eventdata,handles);
%             drtaBrowseTraces('updateBrowseTraces',h);
%         end
        
%         function drtaThresholdPush_Callback(app, handles)
% 
%             h=drtaThresholdSnips;
%             handles.w.drtaThresholdSnips=h;
%             %handles.p.trialNo=1;
%             % Update handles structure here and elsewhere
%             drtaUpdateAllHandlespw(app, handles);
%             drtaThresholdSnips('updateHandles',h,eventdata,handles);
%         end
        
%         function trialNo = getTrialNo(~, handles)
%                 
%             trialNo=handles.p.trialNo;
%         end
%         
%         function actualTrialNo = setTrialNo(app, trialNo)
%             % hObject    handle to drtaBrowseDraPush (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             
%             handles=guidata(hObject);
%             
%             sztrials=size(handles.draq_d.t_trial);
%             actualTrialNo=trialNo;
%             if (trialNo<1)
%                 actualTrialNo=1;
%                 handles.p.trialNo=1;
%             end
%             
%             if (trialNo>sztrials(2))
%                 actualTrialNo=sztrials(2);
%                 handles.p.trialNo=sztrials(2);
%             end
%             
%             if (trialNo>=1)&&(trialNo<=sztrials(2))
%                 actualTrialNo=trialNo;
%                 handles.p.trialNo=trialNo;
%             end
%             
%             % Update handles structure
%             guidata(hObject, handles);
%         end
        
        
    end
    
    methods (Access = public)
        function ReadoutUpdate(app,textUpdate)
            TextPrint = textUpdate;
            OTsize = size(app.OutputText);
            if app.FlagAlpha == true
                app.OutputText{1,1} = append(app.OutputText{1,1},textUpdate);
                app.FlagAlpha = false;
            else
                app.OutputText{(OTsize(1)+1),1} = TextPrint;
            end
            app.ProgressReadout.Value = app.OutputText;
            pause(.1)
        end
        
%         function drtaUpdateAllHandlespw(app,newhandles)
%             % hObject    handle to drtaBrowseDraPush (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             
%             handles=app.drta_handles;
%             handles.p=newhandles.p;
%             handles.draq_p=newhandles.draq_p;
%             % Update handles structure
%             app.drta_handles = handles;
%             
%             if handles.w.drtaBrowseTraces~=0
%                 hObject=handles.w.drtaBrowseTraces;
% %                 handles=guidata(hObject);
%                 handles.p=newhandles.p;
%                 handles.w=newhandles.w;
%                 handles.draq_p=newhandles.draq_p;
%                 % Update the handles structure
% %                 guidata(hObject, handles);
%                 %drtaBrowseTraces('updateBrowseTraces',hObject);
%             end
%             
% %             handles=guidata(gcbo);
%             if handles.w.drtaThresholdSnips~=0
%                 hObject=handles.w.drtaThresholdSnips;
% %                 handles=guidata(hObject);
%                 handles.p=newhandles.p;
%                 handles.w=newhandles.w;
%                 handles.draq_p=newhandles.draq_p;
%                 % Update the handles structure
% %                 guidata(hObject, handles);
% %                 drtaThresholdSnips('drtaThSnSetTrials',hObject,handles);
%                 %drtaThresholdSnips('drtaThresholdAndPlot',hObject,handles);
%             end
%             app.drta_handles = handles;
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Choose default command line output for drta
            %load('C:\Awake Data\drta_preferences.mat');
            prefs=drtaSetPreferences();
            app.drta_handles.p=prefs;
            app.drta_handles.output = app;
            app.drta_handles.w.drta = app;
            app.drta_handles.time_before_FV = 1;
            %app.drta_handles.p.trialNo=1;
%             app.drta_handles.w.drta=hObject;
            app.drta_handles.w.drtaBrowseTraces=0;
            app.drta_handles.w.drtaThresholdSnips=0;
            app.drta_handles.p.threexsdexists(1:16)=0;
            % 
            % %Populate string for drtaVersion modification date
            % if strcmp('diegorereposmbp.ucdenver.pvt',getComputerName)||strcmp('diego-restrepos-macbook-pro.local',getComputerName)
            %     p = mfilename('fullpath');
            %     fileDirectory = fileparts(p);
            %     moddatedir = dir(fileDirectory);
            %     moddatetag = moddatedir.date;
            %     set(app.drta_handles.drtaVersion,'String',['ver ',moddatetag]);
            % end

            
            %{
            Setting up initial parameters/conditions
            %}
            app.drta_handles.p.trialNo=1;
            app.drta_handles.p.low_filter=1000;
            app.drta_handles.p.high_filter=5000;
            app.drta_handles.p.whichPlot=1;
            app.drta_handles.p.lastTrace=-1;
            app.drta_handles.p.exclude_secs=2;
            app.drta_handles.p.set2p5SD=0;
            app.drta_handles.p.setm2p5SD=0;
            app.drta_handles.p.setnxSD=0;
            app.drta_handles.p.nxSD=2.5;
            app.drta_handles.p.showLicks=1;
            app.drta_handles.p.exc_sn=0;
            app.drta_handles.p.read_entire_file=0;
            app.drta_handles.p.setThr=0;
            app.drta_handles.p.thrToSet=0;


        end

        % Value changed function: SelectFile_Button
        function SelectFile_ButtonValueChanged(app, event)
            %{
            Calling a user interface to select the file that needs to be
            opened. The output is the file name and path.
            %}
            [FileName,PathName] = uigetfile({'*.rhd';'*.dg'},'Select rhd or dg file to open');
            app.drta_handles.p.fullName=[PathName,FileName];
            app.drta_handles.p.FileName=FileName;
            app.drta_handles.p.PathName=PathName;
            app.file_name.Value = app.drta_handles.p.FileName;
            
        end

        % Value changed function: Load_Button
        function Load_ButtonValueChanged(app, event)
            %{
            Loading the file that the user selected and opening the visual
            GUI for user interaction.
            %}
            app.FlagAlpha = true;
            protocolPick = app.type_DropDown.Value;
            switch protocolPick
                case 'dropcspm'
                    app.drta_handles.p.which_protocol = 1;
                case 'laser (Ming)'
                    app.drta_handles.p.which_protocol = 2;
                case 'dropcnsampler'
                    app.drta_handles.p.which_protocol = 3;
                case 'laser (Merouann)'
                    app.drta_handles.p.which_protocol = 4;
                case 'dropc_conc'
                    app.drta_handles.p.which_protocol = 5;
                case 'dropcspm_hf'
                    app.drta_handles.p.which_protocol = 6;
                case 'continuous'
                    app.drta_handles.p.which_protocol = 7;
                case 'working_memory'
                    app.drta_handles.p.which_protocol = 8;
            end
            app.drta_handles.trial_duration=app.trial_amt.Value;
            app.drta_handles.pre_dt=app.dt_amt.Value;
            app.dt_amt.Enable = false;
            app.trial_amt.Enable = false;
            app.type_DropDown.Enable = false;
            app.OutputText{1,1} = 'Loading ';
            
            app.ReadoutUpdate(protocolPick);
            if strcmp(app.drta_handles.p.FileName(end-2:end),'rhd')
                open_rhd_Callback(app);
            elseif strcmp(app.drta_handles.p.FileName(end-2:end),'.dg')
                drtaOpenDG_Callback(app);
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectFile_Button = uibutton(app.UIFigure, 'state');
            app.SelectFile_Button.ValueChangedFcn = createCallbackFcn(app, @SelectFile_ButtonValueChanged, true);
            app.SelectFile_Button.Text = 'Choose File (.rhd or .dg)';
            app.copyright_Label = uilabel(app.UIFigure);
            app.copyright_Label.Text = 'drta version 3.0 copyright Diego Restrepo';
            app.Load_Button = uibutton(app.UIFigure, 'state');
            app.Load_Button.ValueChangedFcn = createCallbackFcn(app, @Load_ButtonValueChanged, true);
            app.Load_Button.Text = 'Load File';
            app.ProgressReadout = uitextarea(app.UIFigure);
            app.type_DropDown = uidropdown(app.UIFigure);
            app.type_DropDown.Items = {'dropcspm', 'laser (Ming)', 'dropcnsampler', 'laser (Merouann)', 'dropc_conc', 'dropcspm_hf', 'continuous', 'working_memory'};
            app.type_DropDown.Value = 'dropcspm';
            app.dt_Label = uilabel(app.UIFigure);
            app.dt_Label.Text = 'dt before odor end (s)';
            app.dt_amt = uieditfield(app.UIFigure, 'numeric');
            app.dt_amt.Value = 6;
            app.Trial_Label = uilabel(app.UIFigure);
            app.Trial_Label.Text = 'Trial duration (s)';
            app.trial_amt = uieditfield(app.UIFigure, 'numeric');
            app.trial_amt.Value = 9;
            app.file_name = uitextarea(app.UIFigure);
            app.file_name.Value = {''; 'No File Selected'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end