classdef drtaBrowseTraces < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        GridLayout       matlab.ui.container.GridLayout
        NWB_CheckBox     matlab.ui.control.CheckBox
        SaveButton       matlab.ui.control.StateButton
        NWB_Label        matlab.ui.control.Label
        type_DropDown    matlab.ui.control.DropDown
        DTS_amt          matlab.ui.control.NumericEditField
        thr_Label        matlab.ui.control.Label
        thr_amt          matlab.ui.control.NumericEditField
        nxSD_amt         matlab.ui.control.NumericEditField
        nxSD_Label       matlab.ui.control.Label
        max_amt          matlab.ui.control.NumericEditField
        max_Label        matlab.ui.control.Label
        min_Label        matlab.ui.control.Label
        min_amt          matlab.ui.control.NumericEditField
        Sub_16           matlab.ui.control.DropDown
        Sub_15           matlab.ui.control.DropDown
        Sub_14           matlab.ui.control.DropDown
        Sub_13           matlab.ui.control.DropDown
        Sub_12           matlab.ui.control.DropDown
        Sub_11           matlab.ui.control.DropDown
        Sub_10           matlab.ui.control.DropDown
        Sub_09           matlab.ui.control.DropDown
        Sub_08           matlab.ui.control.DropDown
        Sub_07           matlab.ui.control.DropDown
        Sub_06           matlab.ui.control.DropDown
        Sub_04           matlab.ui.control.DropDown
        Sub_03           matlab.ui.control.DropDown
        Sub_02           matlab.ui.control.DropDown
        Diff_CheckBox    matlab.ui.control.CheckBox
        Sub_05           matlab.ui.control.DropDown
        Sub_01           matlab.ui.control.DropDown
        MClust_Button    matlab.ui.control.StateButton
        uV_Label         matlab.ui.control.Label
        Licks_CheckBox   matlab.ui.control.CheckBox
        Yrange_DropDown  matlab.ui.control.DropDown
        Disp_DropDown    matlab.ui.control.DropDown
        Choice_DropDown  matlab.ui.control.DropDown
        Disp_Label       matlab.ui.control.Label
        DTS_Larrow       matlab.ui.control.Button
        DTS_Rarrow       matlab.ui.control.Button
        Tnum_Label       matlab.ui.control.Label
        DTS_Label        matlab.ui.control.Label
        Interval_Label   matlab.ui.control.Label
        Interval_amt     matlab.ui.control.NumericEditField
        Tnum_amt         matlab.ui.control.NumericEditField
        Tnum_Rarrow      matlab.ui.control.Button
        Tnum_Larrow      matlab.ui.control.Button
        Yrange_amt       matlab.ui.control.NumericEditField
        Yrange_Label     matlab.ui.control.Label
    end

    
    properties (Access = private)
        drtaPlot_handles = struct; % Description
        drta_Main % Links the main application
    end
    
    
    methods (Access = public)
        
        function drtaBrTr3xSD_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaBrTr3xSD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaBrTr3xSD
            
            handles.p.do_3xSD=get(hObject,'Value');
            % Update handles structure
            guidata(hObject, handles);
            updateBrowseTraces(app, hObject);
        end
        
        function drtaEditExCh_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaEditExCh (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of drtaEditExCh as text
            %        str2double(get(hObject,'String')) returns contents of drtaEditExCh as a double
            handles.p.exc_sn_ch=str2num(get(hObject,'String'));
            %set(hObject,'String',num2str(handles.p.exc_sn_thr_9to16));
            
            if handles.p.exc_sn_ch>16
                handles.p.exc_sn_ch=16;
            end
            
            if handles.p.exc_sn_ch<1
                handles.p.exc_sn_ch=1;
            end


            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
            drtaPlotBrowseTraces(handles);
        end
        
        function drtaEditExCh_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to drtaEditExCh (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function drtaEditExcSn_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaEditExcSn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of drtaEditExcSn as text
            %        str2double(get(hObject,'String')) returns contents of drtaEditExcSn as a double
            handles.p.exc_sn_thr=str2num(get(hObject,'String'));
            %set(hObject,'String',num2str(handles.p.display_interval));
            
            if ~isfield(handles.p,'exc_sn_ch')
                handles.p.exc_sn_ch=1;
                set(handles.drtaEditExCh,'String',num2str(handles.p.exc_sn_ch));
            end
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
            drtaPlotBrowseTraces(handles);
        end
        
        function drtaEditExcSn_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to drtaEditExcSn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function up = getUpdatePreview(app, hObject)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            handles=guidata(hObject);
            up = handles.p.update_preview;
        end
        
        function ylim = getYlim(app, hObject)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            handles=guidata(hObject);
            ylim = handles.p.prev_ylim;
        end
        
        function setTrialsOutcome(app, handles, trialsOutcome)
            % hObject    handle to drtaBrowseDraPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handlesb=handles.w.drtaBrowseTraces;
            try
            set(handlesb.trialOutcome,'String',trialsOutcome);
            catch
            end
        end
        
        function setUpdatePreview(app, hObject, up)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            handles=guidata(hObject);
            handles.p.update_preview=up;
            
            % Update the handles structure
            guidata(hObject, handles);
        end
        
        function updateBrowseTraces(app)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            handles=guidata(gcbo);
            
            drtaPlotBrowseTraces(handles);
        end
        
        function updateHandles(app, hObject, eventdata, handdrta)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            tic
            handles=guidata(hObject);
            handles.p=handdrta.p;
            handles.w=handdrta.w;
            handles.draq_p=handdrta.draq_p;
            handles.draq_d=handdrta.draq_d;
            handles.w.drtaBrowseTraces=hObject;
            %handles.p.which_channel=1;
            %handles.p.which_display=1;
            %handles.p.do_filter=1;
            handles.w.trialOutcome=handles.trialOutcome;
            % handles.p.doSubtract=0;
            % handles.p.low_filter=1000;
            % handles.p.high_filter=5000;
            % handles.p.subtractCh=[1 8 11 15];


            % Setup popup strings
            popstr={'all','1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Digital'};
            set(handles.drtaPSWhichChan,'String',popstr);
            set(handles.drtaWhichDisplay,'String',popstr);
            set(handles.drtaEditYRange,'String',num2str(handles.draq_p.prev_ylim(1)));
            set(handles.drtaTrialPush,'String',drta('getTrialNo',handles.w.drta));
            set(handles.drtaEditTimeStart,'String',num2str(handles.p.start_display_time));
            set(handles.drtaEditTimeInterval,'String',num2str(handles.p.display_interval));
            set(handles.drtaRadioFilter,'Value',handles.p.do_filter);
            set(handles.drtaLFPmax,'String',num2str(handles.p.lfp.maxLFP))
            set(handles.drtaLFPmin,'String',num2str(handles.p.lfp.minLFP))
            %set(handles.drtaBrTr3xSD,'Value',handles.p.do_3xSD);
            
            %Please note that in the old files there is no handles.p.doSubtract
            if ~isfield(handles.p,'doSubtract')
               handles.p.doSubtract=0;
               handles.p.subtractCh=zeros(1,16);
            end
            
            set(handles.drtaDiff,'Value', handles.p.doSubtract);
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub1,'String',popstr);
            set(handles.drtaSub1,'Value',handles.p.subtractCh(1));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub2,'String',popstr);
            set(handles.drtaSub2,'Value',handles.p.subtractCh(2));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub3,'String',popstr);
            set(handles.drtaSub3,'Value',handles.p.subtractCh(3));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub4,'String',popstr);
            set(handles.drtaSub4,'Value',handles.p.subtractCh(4));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub5,'String',popstr);
            set(handles.drtaSub5,'Value',handles.p.subtractCh(5));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub6,'String',popstr);
            set(handles.drtaSub6,'Value',handles.p.subtractCh(6));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub7,'String',popstr);
            set(handles.drtaSub7,'Value',handles.p.subtractCh(7));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub8,'String',popstr);
            set(handles.drtaSub8,'Value',handles.p.subtractCh(8));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub9,'String',popstr);
            set(handles.drtaSub9,'Value',handles.p.subtractCh(9));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub10,'String',popstr);
            set(handles.drtaSub10,'Value',handles.p.subtractCh(10));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub11,'String',popstr);
            set(handles.drtaSub11,'Value',handles.p.subtractCh(11));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub12,'String',popstr);
            set(handles.drtaSub12,'Value',handles.p.subtractCh(12));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub13,'String',popstr);
            set(handles.drtaSub13,'Value',handles.p.subtractCh(13));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub14,'String',popstr);
            set(handles.drtaSub14,'Value',handles.p.subtractCh(14));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub15,'String',popstr);
            set(handles.drtaSub15,'Value',handles.p.subtractCh(15));
            
            popstr={'1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16','Tet','Avg','No'};
            set(handles.drtaSub16,'String',popstr);
            set(handles.drtaSub16,'Value',handles.p.subtractCh(16));
            
            popstr={'Raw','Wide 4-100','High Theta 6-14','Theta 2-14','Beta 15-36','Gamma1 35-65',...
                'Gamma2 65-95','Gamma 35-95','Spikes 500-5000','Spike var', 'digital'};
            set(handles.drtaRadioFilter,'String',popstr);
            set(handles.drtaRadioFilter,'Value',1);
            if isfield(handles.p,'exc_sn')
               set(handles.drtaRadioExclSn,'Value',handles.p.exc_sn);
            else
                handles.p.exc_sn=0;
            end
            
            handles.draq_p.auto_thr_sign=1;
            
            
            toc
            
            % Update the handles structure
            guidata(hObject, handles);
        end
        
    end
    
    methods (Access = public)
        
        function PlotHandlesUpdate(app,handleOld)
            app.drtaPlot_handles = handleOld;
            disp("updated handles\n")


        end
    end
    
    methods (Access = private)
        
        
        function actualTrialNo = setTrialNo(app,trialNo)
            sztrials=size(app.drtaPlot_handles.draq_d.t_trial);
            actualTrialNo=trialNo;
            if (trialNo<1)
                actualTrialNo=1;
                app.drtaPlot_handles.p.trialNo=1;
            end
            
            if (trialNo>sztrials(2))
                actualTrialNo=sztrials(2);
                app.drtaPlot_handles.p.trialNo=sztrials(2);
            end
            
            if (trialNo>=1)&&(trialNo<=sztrials(2))
                actualTrialNo=trialNo;
                app.drtaPlot_handles.p.trialNo=trialNo;
            end
            
        end
        
        function func14(app)
            %{
            This function is used to adjsut the figured displayed based on
            the user selections from the dropdown menus
            %}
            clf(app.drtaPlot_handles.s_handle,"reset")
            clear app.drtaPlot_handles.s_handle
            PSel = [32,34;30,32;28,30;26,28; ...
                24,26;22,24;20,22;18,20; ...
                16,18;14,16;12,14;10,12; ...
                8,10;6,8;4,6;2,4];
            Wct = 1;
            for ii = 1:16
                
                if ii ~=16
                    SameAsNext = app.drtaPlot_handles.p.subtractCh{1,ii}{:} == app.drtaPlot_handles.p.subtractCh{1,ii+1}{:};
                elseif ii == 16
                    SameAsNext = app.drtaPlot_handles.p.subtractCh{1,ii}{:} == app.drtaPlot_handles.p.subtractCh{1,ii-1}{:};
                end
                if SameAsNext == ture
                    if ~isempty(stPlc)
                        stPlc = ii;
                    end
                else
                    if ~isempty(stPlc)
                        stPlc = ii;
                    end
                    app.drtaPlot_handles.plot.s_handle(Wct)= uiaxes(drta_ax); 
                    app.drtaPlot_handles.plot.s_handle(ii).Layout.Row = [stPlc PSel(ii,2)];
                    app.drtaPlot_handles.plot.s_handle(ii).Layout.Column = [2 9];
                    app.drtaPlot_handles.plot.s_handle(ii).XTickLabels = {};
                    app.drtaPlot_handles.plot.s_handle(ii).YTickLabels = {};
                    app.drtaPlot_handles.plot.s_handle(ii).Title.Visible = "off";
                    app.drtaPlot_handles.plot.s_handle(ii).Box = 'on';
                    currentInner = app.drtaPlot_handles.plot.s_handle(ii).InnerPosition;
                    if ii == 1
                        % lowermost plot is taller due to x-tic labels
                        app.drtaPlot_handles.plot.s_handle(ii).InnerPosition = [currentInner(1), currentInner(2)+10, ...
                            currentInner(3), 40];
                    else
                        app.drtaPlot_handles.plot.s_handle(ii).InnerPosition = [currentInner(1), currentInner(2)+3, ...
                            currentInner(3), 38];
                    end
                end
                stPlc = [];


            end
            drtaGenerateEvents(app.drtaPlot_handles)
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainFunc, drta_Handles)
            
            
            app.drta_Main = MainFunc;
            app.drtaPlot_handles = drta_Handles;

            app.drtaPlot_handles.p.which_c_program = 2;
            app.type_DropDown.Value = 'dropcspm';


            tic
%             app.drtaPlot_handles=guidata(hObject);
%             app.drtaPlot_handles.p=handdrta.p;
%             app.drtaPlot_handles.w=handdrta.w;
%             app.drtaPlot_handles.draq_p=handdrta.draq_p;
%             app.drtaPlot_handles.draq_d=handdrta.draq_d;
            app.drtaPlot_handles.w.drtaBrowseTraces=app;
%             %app.drtaPlot_handles.p.which_channel=1;
%             %app.drtaPlot_handles.p.which_display=1;
%             %app.drtaPlot_handles.p.do_filter=1;
%             app.drtaPlot_handles.w.trialOutcome=handles.trialOutcome;
%             % app.drtaPlot_handles.p.doSubtract=0;
%             % app.drtaPlot_handles.p.low_filter=1000;
%             % app.drtaPlot_handles.p.high_filter=5000;
%             % app.drtaPlot_handles.p.subtractCh=[1 8 11 15];
            
            %Please note that in the old files there is no handles.p.doSubtract
            if ~isfield(app.drtaPlot_handles.p,'doSubtract')
                app.drtaPlot_handles.p.doSubtract=0;
                app.drtaPlot_handles.p.subtractCh=zeros(1,16);
            end
            
            
%             if isfield(app.drtaPlot_handles.p,'exc_sn')
%                 set(app.drtaPlot_handles.drtaRadioExclSn,'Value',app.drtaPlot_handles.p.exc_sn);
%             else
%                 app.drtaPlot_handles.p.exc_sn=0;
%             end
%             
            app.drtaPlot_handles.draq_p.auto_thr_sign=1;
            
            
            toc

            %{
            Loads the locations for each plot
            %}
            drta_ax = app.drtaPlot_handles.w.drtaBrowseTraces.GridLayout;
            for ii = 1:16
                app.drtaPlot_handles.plot.s_handle(ii)= uiaxes(drta_ax); 
                PSel = [32,34;30,32;28,30;26,28; ...
                    24,26;22,24;20,22;18,20; ...
                    16,18;14,16;12,14;10,12; ...
                    8,10;6,8;4,6;2,4];
                app.drtaPlot_handles.plot.s_handle(ii).Layout.Row = [PSel(ii,1) PSel(ii,2)];
                app.drtaPlot_handles.plot.s_handle(ii).Layout.Column = [2 9];
                app.drtaPlot_handles.plot.s_handle(ii).XTickLabels = {};
                app.drtaPlot_handles.plot.s_handle(ii).YTickLabels = {};
                app.drtaPlot_handles.plot.s_handle(ii).Title.Visible = "off";
                app.drtaPlot_handles.plot.s_handle(ii).Box = 'on';
                currentInner = app.drtaPlot_handles.plot.s_handle(ii).InnerPosition;
                if ii == 1
                    % lowermost plot is taller due to x-tic labels
                    app.drtaPlot_handles.plot.s_handle(ii).InnerPosition = [currentInner(1), currentInner(2)+10, ...
                        currentInner(3), 40];
                else
                    app.drtaPlot_handles.plot.s_handle(ii).InnerPosition = [currentInner(1), currentInner(2)+3, ...
                        currentInner(3), 38];
                end
            end
            %{
            Plotting the initial traces using default values
            %}
            drtaPlotBrowseTraces(app.drta_Main,app, app.drtaPlot_handles);
        end

        % Value changed function: Sub_01
        function Sub_01ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,1}{:} = (app.Sub_01.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_02
        function Sub_02ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,2}{:} = (app.Sub_02.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_03
        function Sub_03ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,3}{:} = (app.Sub_03.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_04
        function Sub_04ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,4}{:} = (app.Sub_04.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_05
        function Sub_05ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,5}{:} = (app.Sub_05.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_06
        function Sub_06ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,6}{:} = (app.Sub_06.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_07
        function Sub_07ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,7}{:} = (app.Sub_07.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_08
        function Sub_08ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,8}{:} = (app.Sub_08.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_09
        function Sub_09ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,9}{:} = (app.Sub_09.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_10
        function Sub_10ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,10}{:} = (app.Sub_10.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_11
        function Sub_11ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,11}{:} = (app.Sub_11.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_12
        function Sub_12ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,12}{:} = (app.Sub_12.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_13
        function Sub_13ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,13}{:} = (app.Sub_13.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_14
        function Sub_14ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,14}{:} = (app.Sub_14.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_15
        function Sub_15ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,15}{:} = (app.Sub_15.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Sub_16
        function Sub_16ValueChanged(app, event)
            app.drtaPlot_handles.p.subtractCh{1,16}{:} = (app.Sub_16.Value);
            drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Yrange_amt
        function Yrange_amtValueChanged(app, event)
            %{
            This function works as intended based on the orignal guide GUI
            %}
            y_range = app.Yrange_amt.Value;
%             v_range=y_range* app.drtaPlot_handles.draq_p.pre_gain* app.drtaPlot_handles.draq_p.daq_gain/1000000;
            
                        
            if (app.drtaPlot_handles.p.which_channel==1)
               %Change for all channels
                for ii=1:app.drtaPlot_handles.draq_p.no_spike_ch
            %         if v_range>handles.draq_p.inp_max
            %             handles.draq_p.prev_ylim(ii)=1000000*handles.draq_p.inp_max/(handles.draq_p.pre_gain*handles.draq_p.daq_gain);
            %         else
                    app.drtaPlot_handles.plot.s_handles(ii)=y_range;
                    app.drtaPlot_handles.draq_p.prev_ylim(ii)=y_range;
            %         end4
                end
            else
                ii=app.drtaPlot_handles.p.which_channel-1;
            %     if v_range>handles.draq_p.inp_max
            %         handles.draq_p.prev_ylim(ii)=1000000*handles.draq_p.inp_max(2)/(handles.draq_p.pre_gain*handles.draq_p.daq_gain);
            %     else
                app.drtaPlot_handles.plot.s_handles(ii)=y_range;
                app.drtaPlot_handles.draq_p.prev_ylim(ii)=y_range;
            %     end
            end
%             drta(app.drtaPlot_handles.w.drta,'drtaUpdateAllHandlespw',app.drtaPlot_handles)
            drtaPlotBrowseTraces(app.drtaPlot_handles.w.drta, ...
                app.drtaPlot_handles.w.drtaBrowseTraces, ...
                app.drtaPlot_handles);
        end

        % Value changed function: Yrange_DropDown
        function Yrange_DropDownValueChanged(app, event)
            app.drtaPlot_handles.p.which_channel = app.Yrange_DropDown.Value;
            Yrange_amtValueChanged(app, [])
%             % Update all handles.p structures
%             drta('drtaUpdateAllHandlespw',app.drtaPlot_handles.w.drta,app.drtaPlot_handles);
        end

        % Value changed function: Disp_DropDown
        function Disp_DropDownValueChanged(app, event)
            app.drtaPlot_handles.p.which_display = app.Disp_DropDown.Value;

            if app.drtaPlot_handles.p.which_display==1
               for ii = 1:app.drtaPlot_handles.draq_p.no_spike_ch 
                    app.drtaPlot_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drtaPlot_handles.draq_p.no_spike_ch)*(ii-0.5)+0.92*0.85/app.drtaPlot_handles.draq_p.no_spike_ch;
                    app.drtaPlot_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drtaPlot_handles.draq_p.no_spike_ch)*(ii-0.5);
               end
            else
               ii = app.drtaPlot_handles.p.which_display-1;
               app.drtaPlot_handles.draq_p.fig_max(ii)=0.12+(0.80/app.drtaPlot_handles.draq_p.no_spike_ch)*(app.drtaPlot_handles.draq_p.no_spike_ch-0.5)+0.92*0.85/handles.draq_p.no_spike_ch;
               app.drtaPlot_handles.draq_p.fig_min(ii)=0.12+(0.80/app.drtaPlot_handles.draq_p.no_spike_ch)*(1-0.5);
            end
            
%             % Update all handles.p structures
%             drta('drtaUpdateAllHandlespw',app.drtaPlot_handles.w.drta,app.drtaPlot_handles);
%             drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Interval_amt
        function Interval_amtValueChanged(app, event)
            time_interval = app.Interval_amt.Value;

            if(app.drtaPlot_handles.p.start_display_time+time_interval)>app.drtaPlot_handles.draq_p.sec_per_trigger
               time_interval= app.drtaPlot_handles.draq_p.sec_per_trigger-app.drtaPlot_handles.p.start_display_time;
            end
                
            app.drtaPlot_handles.p.display_interval=time_interval;
            app.Interval_amt.Value = app.drtaPlot_handles.p.display_interval;
%             set(hObject,'String',num2str(handles.p.display_interval));
            
%             % Update all handles.p structures
%             drta('drtaUpdateAllHandlespw',app.drtaPlot_handles.w.drta,app.drtaPlot_handles);
%             drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Button pushed function: Tnum_Larrow
        function Tnum_LarrowButtonPushed(app, event)
            newTrial= app.drtaPlot_handles.p.trialNo-1;
            app.drtaPlot_handles.p.trialNo = setTrialNo(app, newTrial);
%             app.drtaPlot_handles.p.trialNo=drta('setTrialNo',app.drtaPlot_handles.w.drta,newTrial);
            app.Tnum_amt.Value = app.drtaPlot_handles.p.trialNo;
%             set(app.drtaPlot_handles.drtaTrialPush,'String', num2str(app.drtaPlot_handles.p.trialNo));
            %updateBrowseTraces(hObject);

%             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drtaPlot_handles);
            drtaPlotBrowseTraces(app.drta_Main, app, app.drtaPlot_handles);
%             drta('drtaUpdateAllHandlespw',app.drtaPlot_handles.w.drta,app.drtaPlot_handles);
%             drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Button pushed function: Tnum_Rarrow
        function Tnum_RarrowButtonPushed(app, event)
            newTrial=app.drtaPlot_handles.p.trialNo+1;
            app.drtaPlot_handles.p.trialNo = setTrialNo(app, newTrial);
%             app.drtaPlot_handles.p.trialNo=drta('setTrialNo',app.drtaPlot_handles.w.drta,newTrial);
            app.Tnum_amt.Value = app.drtaPlot_handles.p.trialNo;
%             set(app.drtaPlot_handles.drtaTrialPush,'String', num2str(app.drtaPlot_handles.p.trialNo));
            %updateBrowseTraces(hObject);

%             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drtaPlot_handles);
            drtaPlotBrowseTraces(app.drta_Main, app, app.drtaPlot_handles);
%             drta('drtaUpdateAllHandlespw',app.drtaPlot_handles.w.drta,app.drtaPlot_handles);
%             drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: Tnum_amt
        function Tnum_amtValueChanged(app, event)
            app.drtaPlot_handles.p.trialNo = app.Tnum_amt.Value;
            app.drtaPlot_handles.p.trialNo = setTrialNo(app, app.drtaPlot_handles.p.trialNo);
%             app.drtaPlot_handles.p.trialNo=drta('setTrialNo',app.drtaPlot_handles.w.drta, app.drtaPlot_handles.p.trialNo);
            app.Tnum_amt.Value = app.drtaPlot_handles.p.trialNo;
%             set(app.drtaPlot_handles.drtaTrialPush,'String',num2str(app.drtaPlot_handles.p.trialNo));
            
%             % Update all handles.p.structures
%             drta('drtaUpdateAllHandlespw',app.drtaPlot_handles.w.drta,app.drtaPlot_handles);
            PlotHandlesUpdate(app,app.drtaPlot_handles);
            drtaPlotBrowseTraces(app.drta_Main, app, app.drtaPlot_handles);
            % drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Button pushed function: DTS_Larrow
        function DTS_LarrowButtonPushed(app, event)
            if (app.drtaPlot_handles.p.start_display_time-app.drtaPlot_handles.p.display_interval)>app.drtaPlot_handles.draq_p.acquire_display_start
               app.drtaPlot_handles.p.start_display_time= app.drtaPlot_handles.p.start_display_time-app.drtaPlot_handles.p.display_interval;
            end
            app.DTS_amt = app.drtaPlot_handles.drtaEditTimeStart;
%             set(app.drtaPlot_handles.drtaEditTimeStart,'String',num2str(handles.p.start_display_time));
            
%             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drtaPlot_handles);
            drtaPlotBrowseTraces(app.drta_Main, app, app.drtaPlot_handles);
%             drta('drtaUpdateAllHandlespw',app.drtaPlot_handles.w.drta,app.drtaPlot_handles);
%             drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Button pushed function: DTS_Rarrow
        function DTS_RarrowButtonPushed(app, event)
            if (app.drtaPlot_handles.p.start_display_time+app.drtaPlot_handles.p.display_interval)>app.drtaPlot_handles.draq_p.acquire_display_start
               app.drtaPlot_handles.p.start_display_time= app.drtaPlot_handles.p.start_display_time+app.drtaPlot_handles.p.display_interval;
            end
            app.DTS_amt = app.drtaPlot_handles.drtaEditTimeStart;
%             set(app.drtaPlot_handles.drtaEditTimeStart,'String',num2str(handles.p.start_display_time));
            
%             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drtaPlot_handles);
            drtaPlotBrowseTraces(app.drta_Main, app, app.drtaPlot_handles);
%             drta('drtaUpdateAllHandlespw',app.drtaPlot_handles.w.drta,app.drtaPlot_handles);
%             drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: DTS_amt
        function DTS_amtValueChanged(app, event)
            start_time = app.DTS_amt.Value;
            if start_time<app.drtaPlot_handles.draq_p.acquire_display_start
               start_time= app.drtaPlot_handles.draq_p.acquire_display_start;
            end
            
            if (start_time+app.drtaPlot_handles.p.display_interval)>app.drtaPlot_handles.draq_p.sec_per_trigger
               start_time= app.drtaPlot_handles.draq_p.sec_per_trigger-app.drtaPlot_handles.p.display_interval;
            end
            app.drtaPlot_handles.p.start_display_time=start_time;
            app.DTS_amt.Value = app.drtaPlot_handles.p.start_display_time;

%             set(hObject,'String',num2str(handles.p.start_display_time));
            
%             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drtaPlot_handles);
            drtaPlotBrowseTraces(app.drta_Main, app, app.drtaPlot_handles);
%             drta('drtaUpdateAllHandlespw',app.drtaPlot_handles.w.drta,app.drtaPlot_handles);
%             drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: MClust_Button
        function MClust_ButtonValueChanged(app, event)
            drtaGenerateMClust(app.drtaPlot_handles);
            
        end

        % Value changed function: Licks_CheckBox
        function Licks_CheckBoxValueChanged(app, event)
            app.drtaPlot_handles.p.exc_sn = app.Licks_CheckBox.Value;
%             handles.p.exc_sn=get(hObject,'Value');
            %set(hObject,'String',num2str(handles.p.display_interval));
            
%             % Update all handles.p structures
%             drta('drtaUpdateAllHandlespw',app.drtaPlot_handles.w.drta,app.drtaPlot_handles);
%             drtaPlotBrowseTraces(app.drtaPlot_handles);
        end

        % Value changed function: min_amt
        function min_amtValueChanged(app, event)
            app.drtaPlot_handles.p.lfp.minLFP = app.min_amt.Value;
            
            % Update all handles.p structures
            PlotHandlesUpdate(app,app.drtaPlot_handles);
        end

        % Value changed function: max_amt
        function max_amtValueChanged(app, event)
            app.drtaPlot_handles.p.lfp.maxLFP = app.max_amt.Value;

%             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drtaPlot_handles);
%             drta('drtaUpdateAllHandlespw',app.drtaPlot_handles.w.drta,app.drtaPlot_handles);
        end

        % Value changed function: thr_amt
        function thr_amtValueChanged(app, event)
            app.drtaPlot_handles.p.thrToSet = app.thr_amt.Value;
            app.drtaPlot_handles.p.setThr=1;

%             % Update all handles.p structures
            PlotHandlesUpdate(app,app.drtaPlot_handles);
%             drta('drtaUpdateAllHandlespw',app.drtaPlot_handles.w.drta,app.drtaPlot_handles);
        end

        % Value changed function: nxSD_amt
        function nxSD_amtValueChanged(app, event)
            app.drtaPlot_handles.p.nxSD = app.nxSD_amt.Value;
            app.drtaPlot_handles.p.setnxSD=1;

            % Update handles structure
            drtaPlotBrowseTraces(app.drta_Main, app, app.drtaPlot_handles);
            app.drtaPlot_handles.p.setnxSD=0;
            PlotHandlesUpdate(app,app.drtaPlot_handles);
        end

        % Value changed function: Choice_DropDown
        function Choice_DropDownValueChanged(app, event)
            DropChoice = app.Choice_DropDown.Value;

            switch DropChoice
                case 'Raw'
                    app.drtaPlot_handles.p.whichPlot = 1;
                case 'Wide 4-100'
                    app.drtaPlot_handles.p.whichPlot = 2;
                case 'High Theta 6-14'
                    app.drtaPlot_handles.p.whichPlot = 3;
                case 'Theta 2-14'
                    app.drtaPlot_handles.p.whichPlot = 4;
                case 'Beta 15-36'
                    app.drtaPlot_handles.p.whichPlot = 5;
                case 'Gamma1 35-65'
                    app.drtaPlot_handles.p.whichPlot = 6;
                case 'Gamma2 65-95'
                    app.drtaPlot_handles.p.whichPlot = 7;
                case 'Gamma 35-95'
                    app.drtaPlot_handles.p.whichPlot = 8;
                case 'Spikes 500-5000'
                    app.drtaPlot_handles.p.whichPlot = 9;
                case 'Spike var digital'
                    app.drtaPlot_handles.p.whichPlot = 10;
            end

            % Update handles structure
            drtaPlotBrowseTraces(app.drtaPlot_handles.w.drta, ...
                app.drtaPlot_handles.w.drtaBrowseTraces, ...
                app.drtaPlot_handles);
        end

        % Callback function
        function dt_amtValueChanged(app, event)
            app.drtaPlot_handles.pre_dt = ...
                app.dt_amt.Value;
            
        end

        % Value changed function: type_DropDown
        function type_DropDownValueChanged(app, event)
            which_c_program = ...
                app.type_DropDown.Value;
            switch which_c_program
                case 'dropcnsampler'
                    app.drtaPlot_handles.p.which_c_program = 1;
                case 'dropcspm'
                    app.drtaPlot_handles.p.which_c_program = 2;
                case 'background'
                    app.drtaPlot_handles.p.which_c_program = 3;
                case 'spmult'
                    app.drtaPlot_handles.p.which_c_program = 4;
                case 'mspy'
                    app.drtaPlot_handles.p.which_c_program = 5;
                case 'osampler'
                    app.drtaPlot_handles.p.which_c_program = 6;
                case 'spm2mult'
                    app.drtaPlot_handles.p.which_c_program = 7;
                case 'lighton1'
                    app.drtaPlot_handles.p.which_c_program = 8;
                case 'lighton5'
                    app.drtaPlot_handles.p.which_c_program = 9;
                case 'dropcspm_conc'
                    app.drtaPlot_handles.p.which_c_program = 10;
                case 'Laser-triggered'
                    app.drtaPlot_handles.p.which_c_program = 11;
                case 'Laser-Merouann'
                    app.drtaPlot_handles.p.which_c_program = 12;
                case 'dropcspm_hf'
                    app.drtaPlot_handles.p.which_c_program = 13;
                case 'Working_memory'
                    app.drtaPlot_handles.p.which_c_program = 14;
                case 'Continuous'
                    app.drtaPlot_handles.p.which_c_program = 15;
            end
        end

        % Value changed function: SaveButton
        function SaveButtonValueChanged(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to drtaThrSaveMat (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            testb = app.SaveButton.Value;
            disp(testb)
            if (isfield(app.drtaPlot_handles.draq_d,'snip_samp'))
                try
                    app.drtaPlot_handles = rmfield(app.drtaPlot_handles.draq_d,'snip_samp');
                catch
                end

                try
                    app.drtaPlot_handles = rmfield(app.drtaPlot_handles.draq_d,'snip_index');
                catch
                end

                try
                    app.drtaPlot_handles = rmfield(app.drtaPlot_handles.draq_d,'snips');
                catch
                end
            end
            
            if (isfield(app.drtaPlot_handles.draq_d,'noEvents'))
               app.drtaPlot_handles = rmfield(app.drtaPlot_handles.draq_d,'noEvents');
               app.drtaPlot_handles = rmfield(app.drtaPlot_handles.draq_d,'nEvPerType');
               app.drtaPlot_handles = rmfield(app.drtaPlot_handles.draq_d,'nEventTypes');
               app.drtaPlot_handles = rmfield(app.drtaPlot_handles.draq_d,'eventlabels');
            
               try
               app.drtaPlot_handles = rmfield(handles.draq_d,'events');
               app.drtaPlot_handles = rmfield(handles.draq_d,'eventType');
               catch
               end
            
            
               try
               app.drtaPlot_handles = rmfield(handles.draq_d,'blocks');
               catch
               end
            end
            
            drtaGenerateEvents(app.drtaPlot_handles);
            app.SaveButton.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Yrange_Label = uilabel(app.GridLayout);
            app.Yrange_Label.Text = 'Y Range (uV):';
            app.Yrange_amt = uieditfield(app.GridLayout, 'numeric');
            app.Yrange_amt.ValueChangedFcn = createCallbackFcn(app, @Yrange_amtValueChanged, true);
            app.Yrange_amt.Value = 4000;
            app.Tnum_Larrow = uibutton(app.GridLayout, 'push');
            app.Tnum_Larrow.ButtonPushedFcn = createCallbackFcn(app, @Tnum_LarrowButtonPushed, true);
            app.Tnum_Larrow.Text = '<--';
            app.Tnum_Rarrow = uibutton(app.GridLayout, 'push');
            app.Tnum_Rarrow.ButtonPushedFcn = createCallbackFcn(app, @Tnum_RarrowButtonPushed, true);
            app.Tnum_Rarrow.Text = '-->';
            app.Tnum_amt = uieditfield(app.GridLayout, 'numeric');
            app.Tnum_amt.ValueChangedFcn = createCallbackFcn(app, @Tnum_amtValueChanged, true);
            app.Tnum_amt.Value = 1;
            app.Interval_amt = uieditfield(app.GridLayout, 'numeric');
            app.Interval_amt.ValueChangedFcn = createCallbackFcn(app, @Interval_amtValueChanged, true);
            app.Interval_amt.Value = 1;
            app.Interval_Label = uilabel(app.GridLayout);
            app.Interval_Label.Text = 'Interval (sec):';
            app.DTS_Label = uilabel(app.GridLayout);
            app.DTS_Label.Text = 'Display Time Start (sec):';
            app.Tnum_Label = uilabel(app.GridLayout);
            app.Tnum_Label.Text = 'Trial No:';
            app.DTS_Rarrow = uibutton(app.GridLayout, 'push');
            app.DTS_Rarrow.ButtonPushedFcn = createCallbackFcn(app, @DTS_RarrowButtonPushed, true);
            app.DTS_Rarrow.Text = '-->';
            app.DTS_Larrow = uibutton(app.GridLayout, 'push');
            app.DTS_Larrow.ButtonPushedFcn = createCallbackFcn(app, @DTS_LarrowButtonPushed, true);
            app.DTS_Larrow.Text = '<--';
            app.Disp_Label = uilabel(app.GridLayout);
            app.Disp_Label.Text = 'Display:';
            app.Choice_DropDown = uidropdown(app.GridLayout);
            app.Choice_DropDown.Items = {'Raw', 'Wide 4-100', 'High Theta 6-14', 'Theta 2-14', 'Beta 15-36', 'Gamma1 35-65', 'Gamma2 65-95', 'Gamma 35-95', 'Spikes 500-5000', 'Spike var digital'};
            app.Choice_DropDown.ValueChangedFcn = createCallbackFcn(app, @Choice_DropDownValueChanged, true);
            app.Choice_DropDown.Value = 'Raw';
            app.Disp_DropDown = uidropdown(app.GridLayout);
            app.Disp_DropDown.Items = {'all', '01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Digital'};
            app.Disp_DropDown.ValueChangedFcn = createCallbackFcn(app, @Disp_DropDownValueChanged, true);
            app.Disp_DropDown.Value = 'all';
            app.Yrange_DropDown = uidropdown(app.GridLayout);
            app.Yrange_DropDown.Items = {'all', '01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Digital'};
            app.Yrange_DropDown.ValueChangedFcn = createCallbackFcn(app, @Yrange_DropDownValueChanged, true);
            app.Yrange_DropDown.Value = 'all';
            app.Licks_CheckBox = uicheckbox(app.GridLayout);
            app.Licks_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Licks_CheckBoxValueChanged, true);
            app.Licks_CheckBox.Text = 'Exclude Licks';
            app.uV_Label = uilabel(app.GridLayout);
            app.uV_Label.Text = 'uV';
            app.MClust_Button = uibutton(app.GridLayout, 'state');
            app.MClust_Button.ValueChangedFcn = createCallbackFcn(app, @MClust_ButtonValueChanged, true);
            app.MClust_Button.Text = 'MClust';
            app.Sub_01 = uidropdown(app.GridLayout);
            app.Sub_01.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_01.ValueChangedFcn = createCallbackFcn(app, @Sub_01ValueChanged, true);
            app.Sub_01.Value = '01';
            app.Sub_05 = uidropdown(app.GridLayout);
            app.Sub_05.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_05.ValueChangedFcn = createCallbackFcn(app, @Sub_05ValueChanged, true);
            app.Sub_05.Value = '05';
            app.Diff_CheckBox = uicheckbox(app.GridLayout);
            app.Diff_CheckBox.Text = 'Differential';
            app.Sub_02 = uidropdown(app.GridLayout);
            app.Sub_02.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_02.ValueChangedFcn = createCallbackFcn(app, @Sub_02ValueChanged, true);
            app.Sub_02.Value = '02';
            app.Sub_03 = uidropdown(app.GridLayout);
            app.Sub_03.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_03.ValueChangedFcn = createCallbackFcn(app, @Sub_03ValueChanged, true);
            app.Sub_03.Value = '03';
            app.Sub_04 = uidropdown(app.GridLayout);
            app.Sub_04.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_04.ValueChangedFcn = createCallbackFcn(app, @Sub_04ValueChanged, true);
            app.Sub_04.Value = '04';
            app.Sub_06 = uidropdown(app.GridLayout);
            app.Sub_06.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_06.ValueChangedFcn = createCallbackFcn(app, @Sub_06ValueChanged, true);
            app.Sub_06.Value = '06';
            app.Sub_07 = uidropdown(app.GridLayout);
            app.Sub_07.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_07.ValueChangedFcn = createCallbackFcn(app, @Sub_07ValueChanged, true);
            app.Sub_07.Value = '07';
            app.Sub_08 = uidropdown(app.GridLayout);
            app.Sub_08.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_08.ValueChangedFcn = createCallbackFcn(app, @Sub_08ValueChanged, true);
            app.Sub_08.Value = '08';
            app.Sub_09 = uidropdown(app.GridLayout);
            app.Sub_09.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_09.ValueChangedFcn = createCallbackFcn(app, @Sub_09ValueChanged, true);
            app.Sub_09.Value = '09';
            app.Sub_10 = uidropdown(app.GridLayout);
            app.Sub_10.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_10.ValueChangedFcn = createCallbackFcn(app, @Sub_10ValueChanged, true);
            app.Sub_10.Value = '10';
            app.Sub_11 = uidropdown(app.GridLayout);
            app.Sub_11.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_11.ValueChangedFcn = createCallbackFcn(app, @Sub_11ValueChanged, true);
            app.Sub_11.Value = '11';
            app.Sub_12 = uidropdown(app.GridLayout);
            app.Sub_12.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_12.ValueChangedFcn = createCallbackFcn(app, @Sub_12ValueChanged, true);
            app.Sub_12.Value = '12';
            app.Sub_13 = uidropdown(app.GridLayout);
            app.Sub_13.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_13.ValueChangedFcn = createCallbackFcn(app, @Sub_13ValueChanged, true);
            app.Sub_13.Value = '13';
            app.Sub_14 = uidropdown(app.GridLayout);
            app.Sub_14.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_14.ValueChangedFcn = createCallbackFcn(app, @Sub_14ValueChanged, true);
            app.Sub_14.Value = '14';
            app.Sub_15 = uidropdown(app.GridLayout);
            app.Sub_15.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_15.ValueChangedFcn = createCallbackFcn(app, @Sub_15ValueChanged, true);
            app.Sub_15.Value = '15';
            app.Sub_16 = uidropdown(app.GridLayout);
            app.Sub_16.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', 'Tet', 'Avg', 'No'};
            app.Sub_16.ValueChangedFcn = createCallbackFcn(app, @Sub_16ValueChanged, true);
            app.Sub_16.Value = '16';
            app.min_amt = uieditfield(app.GridLayout, 'numeric');
            app.min_amt.ValueChangedFcn = createCallbackFcn(app, @min_amtValueChanged, true);
            app.min_amt.Value = 50;
            app.min_Label = uilabel(app.GridLayout);
            app.min_Label.Text = 'LFP min';
            app.max_Label = uilabel(app.GridLayout);
            app.max_Label.Text = 'LFP max';
            app.max_amt = uieditfield(app.GridLayout, 'numeric');
            app.max_amt.ValueChangedFcn = createCallbackFcn(app, @max_amtValueChanged, true);
            app.max_amt.Value = 3900;
            app.nxSD_Label = uilabel(app.GridLayout);
            app.nxSD_Label.Text = 'Set nxSD';
            app.nxSD_amt = uieditfield(app.GridLayout, 'numeric');
            app.nxSD_amt.ValueChangedFcn = createCallbackFcn(app, @nxSD_amtValueChanged, true);
            app.nxSD_amt.Value = 2.5;
            app.thr_amt = uieditfield(app.GridLayout, 'numeric');
            app.thr_amt.ValueChangedFcn = createCallbackFcn(app, @thr_amtValueChanged, true);
            app.thr_Label = uilabel(app.GridLayout);
            app.thr_Label.Text = 'Set thr (uv)';
            app.DTS_amt = uieditfield(app.GridLayout, 'numeric');
            app.DTS_amt.ValueChangedFcn = createCallbackFcn(app, @DTS_amtValueChanged, true);
            app.type_DropDown = uidropdown(app.GridLayout);
            app.type_DropDown.Items = {'dropcnsampler', 'dropcspm', 'background', 'spmult', 'mspy', 'osampler', 'spm2mult', 'lighton1', 'lighton5', 'dropcspm_conc', 'Laser-triggered', 'Laser-Merouann', 'dropcspm_hf', 'Working_memory', 'Continuous'};
            app.type_DropDown.ValueChangedFcn = createCallbackFcn(app, @type_DropDownValueChanged, true);
            app.type_DropDown.Value = 'dropcnsampler';
            app.NWB_Label = uilabel(app.GridLayout);
            app.NWB_Label.Text = 'NWB format';
            app.SaveButton = uibutton(app.GridLayout, 'state');
            app.SaveButton.ValueChangedFcn = createCallbackFcn(app, @SaveButtonValueChanged, true);
            app.SaveButton.Text = 'Save';
            app.NWB_CheckBox = uicheckbox(app.GridLayout);
            app.NWB_CheckBox.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drtaBrowseTraces(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end