classdef drtaThresholdSnips < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        Panel_3                 matlab.ui.container.Panel
        drtaThrSaveMat          matlab.ui.control.Button
        drtaThWhatDisplay       matlab.ui.control.DropDown
        timeBeforeFV            matlab.ui.control.EditField
        text10                  matlab.ui.control.Label
        drtaThrEnterLowerLimit  matlab.ui.control.EditField
        drtaThrEnterUpperLimit  matlab.ui.control.EditField
        text5                   matlab.ui.control.Label
        text4                   matlab.ui.control.Label
        feature2                matlab.ui.control.DropDown
        feature1                matlab.ui.control.DropDown
        text9                   matlab.ui.control.Label
        text8                   matlab.ui.control.Label
        drtaThCProgram          matlab.ui.control.DropDown
        drtaThrPreview          matlab.ui.control.Button
        Panel_2                 matlab.ui.container.Panel
        Ch01                    matlab.ui.control.StateButton
        Ch02                    matlab.ui.control.StateButton
        Ch03                    matlab.ui.control.StateButton
        Ch04                    matlab.ui.control.StateButton
        Ch05                    matlab.ui.control.StateButton
        Ch06                    matlab.ui.control.StateButton
        Ch07                    matlab.ui.control.StateButton
        Ch08                    matlab.ui.control.StateButton
        Ch09                    matlab.ui.control.StateButton
        Ch10                    matlab.ui.control.StateButton
        Ch11                    matlab.ui.control.StateButton
        Ch12                    matlab.ui.control.StateButton
        Ch13                    matlab.ui.control.StateButton
        Ch14                    matlab.ui.control.StateButton
        Ch15                    matlab.ui.control.StateButton
        Ch16                    matlab.ui.control.StateButton
        ChAllOn                 matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        axes1                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Handles % Main structure that houses all the data and pointers
    end
    
    methods (Access = private)
        function draqPSWhichChan_Callback(app, hObject, eventdata, handles)
            % hObject    handle to draqPSWhichChan (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns draqPSWhichChan contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from draqPSWhichChan
            handles.p.which_channel=get(hObject,'Value');
            % Update handles structure
            guidata(hObject, handles);
        end
        
        function draqPSWhichChan_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to draqPSWhichChan (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function drtaEnterThreshold_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaEnterThreshold (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of drtaEnterThreshold as text
            %        str2double(get(hObject,'String')) returns contents of drtaEnterThreshold as a double
            
            if handles.p.which_channel_th==1
                for ii=1:handles.draq_p.no_spike_ch-1
                    handles.p.threshold(ii)=str2double(get(hObject,'String'));
                end
            else
                handles.p.threshold(handles.p.which_channel_th-1)=str2double(get(hObject,'String'));
            end
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaEnterThreshold_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to drtaEnterThreshold (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function drtaLoadTh_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaLoadTh (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [FileName,PathName] = uigetfile('*.*','Select . mat file to open');
            handles.p.loadThName=[PathName,FileName];
            load(handles.p.loadThName);
            handles.p.threshold=drta_p.threshold;
            handles.p.upper_limit=drta_p.upper_limit;
            handles.p.lower_limit=drta_p.lower_limit;
            
            if handles.p.which_channel_th==1
                set(handles.drtaThrEnterLowerLimit,'String',num2str(handles.p.lower_limit(1)));
                set(handles.drtaThrEnterUpperLimit,'String',num2str(handles.p.upper_limit(1)));
                set(handles.drtaEnterThreshold,'String',num2str(handles.p.threshold(1)));
            else
                set(handles.drtaThrEnterLowerLimit,'String',num2str(handles.p.lower_limit(handles.p.which_channel_th-1)));
                set(handles.drtaThrEnterUpperLimit,'String',num2str(handles.p.upper_limit(handles.p.which_channel_th-1)));
                set(handles.drtaEnterThreshold,'String',num2str(handles.p.threshold(handles.p.which_channel_th-1)));
            end
            
            
            handles.p.ch_processed=drta_p.ch_processed;
            set(handles.drtaThCh1,'Value',handles.p.ch_processed(1));
            set(handles.drtaThCh2,'Value',handles.p.ch_processed(2));
            set(handles.drtaThCh3,'Value',handles.p.ch_processed(3));
            set(handles.drtaThCh4,'Value',handles.p.ch_processed(4));
            set(handles.drtaThCh5,'Value',handles.p.ch_processed(5));
            set(handles.drtaThCh6,'Value',handles.p.ch_processed(6));
            set(handles.drtaThCh7,'Value',handles.p.ch_processed(7));
            set(handles.drtaThCh8,'Value',handles.p.ch_processed(8));
            set(handles.drtaThCh9,'Value',handles.p.ch_processed(9));
            set(handles.drtaThCh10,'Value',handles.p.ch_processed(10));
            set(handles.drtaThCh11,'Value',handles.p.ch_processed(11));
            set(handles.drtaThCh12,'Value',handles.p.ch_processed(12));
            set(handles.drtaThCh13,'Value',handles.p.ch_processed(13));
            set(handles.drtaThCh14,'Value',handles.p.ch_processed(14));
            set(handles.drtaThCh15,'Value',handles.p.ch_processed(15));
            set(handles.drtaThCh16,'Value',handles.p.ch_processed(16));
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaPSWhichChan_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaPSWhichChan (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns drtaPSWhichChan contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from drtaPSWhichChan
            
            old_which=handles.p.which_channel_th;
            handles.p.which_channel_th=get(hObject,'Value');
            if (handles.p.which_channel_th==1)
                if (old_which~=1)
                   old_thr=handles.p.threshold(old_which-1);
                   for ii=1:handles.draq_p.no_spike_ch-1
                      handles.p.threshold(ii) = old_thr;
                   end
                end
            else
                set(handles.drtaEnterThreshold,'String',num2str(handles.p.threshold(handles.p.which_channel_th-1)));
            end
            % Update handles structure
            guidata(hObject, handles);
        end
        
        function drtaThCh10_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh10
            handles.p.ch_processed(10)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh11_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh11 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh11
            handles.p.ch_processed(11)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh12_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh12 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh12
            handles.p.ch_processed(12)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh13_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh13 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh13
            
            handles.p.ch_processed(13)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh14_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh14 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh14
            handles.p.ch_processed(14)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh15_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh15 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh15
            
            handles.p.ch_processed(15)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh16_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh16 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh16
            handles.p.ch_processed(16)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh1_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh1
            handles.p.ch_processed(1)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh2_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh2
            
            handles.p.ch_processed(2)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh3_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh3
            handles.p.ch_processed(3)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh4_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh4
            handles.p.ch_processed(4)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh5_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh5
            handles.p.ch_processed(5)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh6_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh6
            handles.p.ch_processed(6)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh7_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh7
            handles.p.ch_processed(7)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh8_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh8
            handles.p.ch_processed(8)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThCh9_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThCh9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of drtaThCh9
            handles.p.ch_processed(9)=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function drtaThSnSetTrials(app, hObject, handles)
            % hObject    handle to drtaTrialAll (see GCBO)
            % handles    structure with handles and user data (see GUIDATA)
            
            set(handles.drtaTrialCh1,'Value',handles.p.trial_ch_processed(1,handles.p.trialNo));
            set(handles.drtaTrialCh2,'Value',handles.p.trial_ch_processed(2,handles.p.trialNo));
            set(handles.drtaTrialCh3,'Value',handles.p.trial_ch_processed(3,handles.p.trialNo));
            set(handles.drtaTrialCh4,'Value',handles.p.trial_ch_processed(4,handles.p.trialNo));
            set(handles.drtaTrialCh5,'Value',handles.p.trial_ch_processed(5,handles.p.trialNo));
            set(handles.drtaTrialCh6,'Value',handles.p.trial_ch_processed(6,handles.p.trialNo));
            set(handles.drtaTrialCh7,'Value',handles.p.trial_ch_processed(7,handles.p.trialNo));
            set(handles.drtaTrialCh8,'Value',handles.p.trial_ch_processed(8,handles.p.trialNo));
            set(handles.drtaTrialCh9,'Value',handles.p.trial_ch_processed(9,handles.p.trialNo));
            set(handles.drtaTrialCh10,'Value',handles.p.trial_ch_processed(10,handles.p.trialNo));
            set(handles.drtaTrialCh11,'Value',handles.p.trial_ch_processed(11,handles.p.trialNo));
            set(handles.drtaTrialCh12,'Value',handles.p.trial_ch_processed(12,handles.p.trialNo));
            set(handles.drtaTrialCh13,'Value',handles.p.trial_ch_processed(13,handles.p.trialNo));
            set(handles.drtaTrialCh14,'Value',handles.p.trial_ch_processed(14,handles.p.trialNo));
            set(handles.drtaTrialCh15,'Value',handles.p.trial_ch_processed(15,handles.p.trialNo));
            set(handles.drtaTrialCh16,'Value',handles.p.trial_ch_processed(16,handles.p.trialNo));
            
            
            set(handles.drtaTrialAll,'Value',handles.p.trial_allch_processed(1,handles.p.trialNo));
        end
        
        function drtaThresholdAndPlot(app, hObject, handles)
            % hObject    handle to drtaThresholdPush (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            drtaGenerateSnips(hObject, handles);
            handles=guidata(hObject);
            
            switch handles.p.which_display_th
            
                case 1
                    drtaPlotPileup(handles);
                case 2
                    drtaPlotFeatures(handles);
                    %drtaPlotSnips(handles);
                otherwise
                    drtaPlotMV(handles);
            end
        end
        
        function drtaThresholdSnipsSet25_Callback(app, hObject, eventdata, handles)
            % hObject    handle to drtaThresholdSnipsSet25 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            drtaSetThreshold25(hObject, handles);
            handles=guidata(hObject);
            if handles.p.which_channel_th==1
               set(handles.drtaEnterThreshold,'String',num2str(handles.p.threshold(1)));
            else
               set(handles.drtaEnterThreshold,'String',num2str(handles.p.threshold(handles.p.which_channel_th-1)));
            end
            %drtaThresholdAndPlot(hObject, handles);
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function up = getUpdatePreview(app, hObject)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            handles=guidata(hObject);
            up = handles.p.update_preview;
        end
        
        function ylim = getYlim(app, hObject)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            handles=guidata(hObject);
            ylim = handles.p.prev_ylim;
        end
        
        function setUpdatePreview(app, hObject, up)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            handles=guidata(hObject);
            handles.p.update_preview=up;
            
            % Update the handles structure
            guidata(hObject, handles);
        end
        
        function updateHandles(app, hObject, eventdata, handdrta)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            tic
            handles=guidata(hObject);
            handles.p=handdrta.p;
            handles.w=handdrta.w;
            handles.draq_p=handdrta.draq_p;
            handles.draq_d=handdrta.draq_d;
            handles.w.drtaThresholdSnips=hObject;
            %handles.p.which_channel=1;
            
            
            % Setup popup strings
            % popstr={'all','1','2', '3','4','5','6','7','8','9','10','11','12','13','14','15','16'};
            % set(handles.drtaPSWhichChan,'String',popstr);
            popstr={'Pileup','Features','Mean-Value'};
            set(handles.drtaThWhatDisplay,'String',popstr);
            popstr={'dropcnsampler','dropcspm','background','spmult','mspy','osampler',...
                'spm2mult','lighton1','lighton5','dropcspm_conc','Laser-triggered','Laser-Merouann','dropcspm_hf','Working_memory','Continuous'};
            set(handles.drtaThCProgram,'String',popstr);,
            popstr={'p_v','pc1','pc2','pc3','w1','w2','w3','w4','w5','w6','w7','w8','w9','w10','w11',...
                'w12','w13','w14','w15','w16','w17','w18','w19','w20','w21','w22','w23','w24','w25'};
            set(handles.feature1,'String',popstr)
            handles.draq_p.feature1=1;
            popstr={'p_v','pc1','pc2','pc3','w1','w2','w3','w4','w5','w6','w7','w8','w9','w10','w11',...
                'w12','w13','w14','w15','w16','w17','w18','w19','w20','w21','w22','w23','w24','w25'};
            set(handles.feature2,'String',popstr)
            handles.draq_p.feature2=2;
            if handles.p.which_channel_th==1
                %set(handles.drtaEnterThreshold,'String',num2str(handles.p.threshold(1)));
                set(handles.drtaThrEnterUpperLimit,'String',num2str(handles.p.upper_limit(1)));
                set(handles.drtaThrEnterLowerLimit,'String',num2str(handles.p.lower_limit(1)));
            else
               set(handles.drtaEnterThreshold,'String',num2str(handles.p.threshold(handles.p.which_channel_th-1)));
               set(handles.drtaThrEnterUpperLimit,'String',num2str(handles.p.which_channel_th-1));
               set(handles.drtaThrEnterLowerLimit,'String',num2str(handles.p.which_channel_th-1));
            end
            
            % set(handles.drtaThCh1,'Value',handles.p.ch_processed(1));
            % set(handles.drtaThCh2,'Value',handles.p.ch_processed(2));
            % set(handles.drtaThCh3,'Value',handles.p.ch_processed(3));
            % set(handles.drtaThCh4,'Value',handles.p.ch_processed(4));
            % set(handles.drtaThCh5,'Value',handles.p.ch_processed(5));
            % set(handles.drtaThCh6,'Value',handles.p.ch_processed(6));
            % set(handles.drtaThCh7,'Value',handles.p.ch_processed(7));
            % set(handles.drtaThCh8,'Value',handles.p.ch_processed(8));
            % set(handles.drtaThCh9,'Value',handles.p.ch_processed(9));
            % set(handles.drtaThCh10,'Value',handles.p.ch_processed(10));
            % set(handles.drtaThCh11,'Value',handles.p.ch_processed(11));
            % set(handles.drtaThCh12,'Value',handles.p.ch_processed(12));
            % set(handles.drtaThCh13,'Value',handles.p.ch_processed(13));
            % set(handles.drtaThCh14,'Value',handles.p.ch_processed(14));
            % set(handles.drtaThCh15,'Value',handles.p.ch_processed(15));
            % set(handles.drtaThCh16,'Value',handles.p.ch_processed(16));


            set(handles.drtaTrialCh1,'Value',handles.p.trial_ch_processed(1,handles.p.trialNo));
            set(handles.drtaTrialCh2,'Value',handles.p.trial_ch_processed(2,handles.p.trialNo));
            set(handles.drtaTrialCh3,'Value',handles.p.trial_ch_processed(3,handles.p.trialNo));
            set(handles.drtaTrialCh4,'Value',handles.p.trial_ch_processed(4,handles.p.trialNo));
            set(handles.drtaTrialCh5,'Value',handles.p.trial_ch_processed(5,handles.p.trialNo));
            set(handles.drtaTrialCh6,'Value',handles.p.trial_ch_processed(6,handles.p.trialNo));
            set(handles.drtaTrialCh7,'Value',handles.p.trial_ch_processed(7,handles.p.trialNo));
            set(handles.drtaTrialCh8,'Value',handles.p.trial_ch_processed(8,handles.p.trialNo));
            set(handles.drtaTrialCh9,'Value',handles.p.trial_ch_processed(9,handles.p.trialNo));
            set(handles.drtaTrialCh10,'Value',handles.p.trial_ch_processed(10,handles.p.trialNo));
            set(handles.drtaTrialCh11,'Value',handles.p.trial_ch_processed(11,handles.p.trialNo));
            set(handles.drtaTrialCh12,'Value',handles.p.trial_ch_processed(12,handles.p.trialNo));
            set(handles.drtaTrialCh13,'Value',handles.p.trial_ch_processed(13,handles.p.trialNo));
            set(handles.drtaTrialCh14,'Value',handles.p.trial_ch_processed(14,handles.p.trialNo));
            set(handles.drtaTrialCh15,'Value',handles.p.trial_ch_processed(15,handles.p.trialNo));
            set(handles.drtaTrialCh16,'Value',handles.p.trial_ch_processed(16,handles.p.trialNo));
            
            
            set(handles.drtaTrialAll,'Value',handles.p.trial_allch_processed(1,handles.p.trialNo));
            toc
            
            % Update the handles structure
            guidata(hObject, handles);
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
        
        function update_p(app, hObject, handdrta)
            % hObject    handle to drsDisplaySingle
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            
            handles=guidata(hObject);
            handles.p=handdrta.p;
            % Update the handles structure
            guidata(hObject, handles);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function drtaThresholdSnips_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to drtaThresholdSnips (see VARARGIN)
            
            % Choose default command line output for drtaThresholdSnips
%             handles.output = hObject;
            
            app.Handles = varargin{1,2};
            % Update handles structure
%             guidata(hObject, handles);
        end

        % Button pushed function: ChAllOn
        function ChAllOn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to allOn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            app.Handles.p.trial_ch_processed(1:16,1:app.Handles.draq_d.noTrials)=ones(16,app.Handles.draq_d.noTrials);
            app.Handles.p.trial_allch_processed(1,1:app.Handles.draq_d.noTrials)=ones(1,app.Handles.draq_d.noTrials);
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',app.Handles.w.drta,handles);
        end

        % Value changed function: drtaThCProgram
        function drtaThCProgram_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to drtaThCProgram (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns drtaThCProgram contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from drtaThCProgram
            
            
%             handles.p.which_c_program=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',app.Handles.w.drta,handles);
        end

        % Value changed function: drtaThWhatDisplay
        function drtaThWhatDisplay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to drtaThWhatDisplay (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns drtaThWhatDisplay contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from drtaThWhatDisplay
            
%             handles.p.which_display_th=get(hObject,'Value');
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',app.Handles.w.drta,handles);
        end

        % Value changed function: drtaThrEnterLowerLimit
        function drtaThrEnterLowerLimit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to drtaThrEnterLowerLimit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of drtaThrEnterLowerLimit as text
            %        str2double(get(hObject,'String')) returns contents of drtaThrEnterLowerLimit as a double
            
            if app.Handles.p.which_channel_th==1
                for ii=1:app.Handles.draq_p.no_spike_ch-1
                    app.Handles.p.lower_limit(ii)=str2double(app.drtaThrEnterLowerLimit.Value);
                end
            else
                app.Handles.p.lower_limit(app.Handles.p.which_channel_th-1)=str2double(app.drtaThrEnterLowerLimit.Value);
            end
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end

        % Value changed function: drtaThrEnterUpperLimit
        function drtaThrEnterUpperLimit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to drtaThrEnterUpperLimit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of drtaThrEnterUpperLimit as text
            %        str2double(get(hObject,'String')) returns contents of drtaThrEnterUpperLimit as a double
            
            if app.Handles.p.which_channel_th==1
                for ii=1:app.Handles.draq_p.no_spike_ch-1
                    app.Handles.p.upper_limit(ii)=str2double(app.drtaThrEnterUpperLimit.Value());
                end
            else
                app.Handles.p.upper_limit(app.Handles.p.which_channel_th-1)=str2double(app.drtaThrEnterUpperLimit.Value());
            end
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end

        % Button pushed function: drtaThrPreview
        function drtaThrPreview_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to drtaThrPreview (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Update all handles.p structures
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
            drtaThresholdAndPlot(app, app.Handles);
        end

        % Button pushed function: drtaThrSaveMat
        function drtaThrSaveMat_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
%             [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to drtaThrSaveMat (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if (isfield(app.Handles.draq_d,'snip_samp'))
                try
                    app.Handles = rmfield(app.Handles.draq_d,'snip_samp');
                catch
                end

                try
                    app.Handles = rmfield(app.Handles.draq_d,'snip_index');
                catch
                end

                try
                    app.Handles = rmfield(app.Handles.draq_d,'snips');
                catch
                end
            end
            
            if (isfield(app.Handles.draq_d,'noEvents'))
               app.Handles = rmfield(app.Handles.draq_d,'noEvents');
               app.Handles = rmfield(app.Handles.draq_d,'nEvPerType');
               app.Handles = rmfield(app.Handles.draq_d,'nEventTypes');
               app.Handles = rmfield(app.Handles.draq_d,'eventlabels');
            
               try
               app.Handles = rmfield(handles.draq_d,'events');
               app.Handles = rmfield(handles.draq_d,'eventType');
               catch
               end
            
            
               try
               app.Handles = rmfield(handles.draq_d,'blocks');
               catch
               end
            end
            
            drtaGenerateEvents(app.Handles);
        end

        % Value changed function: feature1
        function feature1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to feature1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns feature1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from feature1
            handles.draq_p.feature1=get(hObject,'Value');
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end

        % Value changed function: feature2
        function feature2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to feature2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns feature2 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from feature2
            
            handles.draq_p.feature2=get(hObject,'Value');
            drta('drtaUpdateAllHandlespw',handles.w.drta,handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'drtaThresholdSnips';
            app.figure1.Tag = 'figure1';
            app.Panel = uipanel(app.figure1);
            app.axes1 = uiaxes(app.Panel);
            app.axes1.Tag = 'axes1';
            app.Panel_2 = uipanel(app.figure1);
            app.ChAllOn = uibutton(app.Panel_2, 'push');
            app.ChAllOn.ButtonPushedFcn = createCallbackFcn(app, @ChAllOn_Callback, true);
            app.ChAllOn.Tag = 'allOn';
            app.ChAllOn.Text = 'All on';
            app.Ch16 = uibutton(app.Panel_2, 'state');
            app.Ch16.Text = '16';
            app.Ch15 = uibutton(app.Panel_2, 'state');
            app.Ch15.Text = '15';
            app.Ch14 = uibutton(app.Panel_2, 'state');
            app.Ch14.Text = '14';
            app.Ch13 = uibutton(app.Panel_2, 'state');
            app.Ch13.Text = '13';
            app.Ch12 = uibutton(app.Panel_2, 'state');
            app.Ch12.Text = '12';
            app.Ch11 = uibutton(app.Panel_2, 'state');
            app.Ch11.Text = '11';
            app.Ch10 = uibutton(app.Panel_2, 'state');
            app.Ch10.Text = '10';
            app.Ch09 = uibutton(app.Panel_2, 'state');
            app.Ch09.Text = '09';
            app.Ch08 = uibutton(app.Panel_2, 'state');
            app.Ch08.Text = '08';
            app.Ch07 = uibutton(app.Panel_2, 'state');
            app.Ch07.Text = '07';
            app.Ch06 = uibutton(app.Panel_2, 'state');
            app.Ch06.Text = '06';
            app.Ch05 = uibutton(app.Panel_2, 'state');
            app.Ch05.Text = '05';
            app.Ch04 = uibutton(app.Panel_2, 'state');
            app.Ch04.Text = '04';
            app.Ch03 = uibutton(app.Panel_2, 'state');
            app.Ch03.Text = '03';
            app.Ch02 = uibutton(app.Panel_2, 'state');
            app.Ch02.Text = '02';
            app.Ch01 = uibutton(app.Panel_2, 'state');
            app.Ch01.Text = '01';
            app.Panel_3 = uipanel(app.figure1);
            app.drtaThrPreview = uibutton(app.Panel_3, 'push');
            app.drtaThrPreview.ButtonPushedFcn = createCallbackFcn(app, @drtaThrPreview_Callback, true);
            app.drtaThrPreview.Tag = 'drtaThrPreview';
            app.drtaThrPreview.Text = 'Preview';
            app.drtaThCProgram = uidropdown(app.Panel_3);
            app.drtaThCProgram.Items = {'Pop-up Menu'};
            app.drtaThCProgram.ValueChangedFcn = createCallbackFcn(app, @drtaThCProgram_Callback, true);
            app.drtaThCProgram.Tag = 'drtaThCProgram';
            app.drtaThCProgram.Value = 'Pop-up Menu';
            app.text8 = uilabel(app.Panel_3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Feature 1:';
            app.text9 = uilabel(app.Panel_3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Feature 2:';
            app.feature1 = uidropdown(app.Panel_3);
            app.feature1.Items = {'p_v'};
            app.feature1.ValueChangedFcn = createCallbackFcn(app, @feature1_Callback, true);
            app.feature1.Tag = 'feature1';
            app.feature1.Value = 'p_v';
            app.feature2 = uidropdown(app.Panel_3);
            app.feature2.Items = {'PC1'};
            app.feature2.ValueChangedFcn = createCallbackFcn(app, @feature2_Callback, true);
            app.feature2.Tag = 'feature2';
            app.feature2.Value = 'PC1';
            app.text4 = uilabel(app.Panel_3);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Upper limit (uv):';
            app.text5 = uilabel(app.Panel_3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Lower limit (uv):';
            app.drtaThrEnterUpperLimit = uieditfield(app.Panel_3, 'text');
            app.drtaThrEnterUpperLimit.ValueChangedFcn = createCallbackFcn(app, @drtaThrEnterUpperLimit_Callback, true);
            app.drtaThrEnterUpperLimit.Tag = 'drtaThrEnterUpperLimit';
            app.drtaThrEnterUpperLimit.Value = 'Edit Text';
            app.drtaThrEnterLowerLimit = uieditfield(app.Panel_3, 'text');
            app.drtaThrEnterLowerLimit.ValueChangedFcn = createCallbackFcn(app, @drtaThrEnterLowerLimit_Callback, true);
            app.drtaThrEnterLowerLimit.Tag = 'drtaThrEnterLowerLimit';
            app.drtaThrEnterLowerLimit.Value = 'Edit Text';
            app.text10 = uilabel(app.Panel_3);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Time before FV (sec):';
            app.timeBeforeFV = uieditfield(app.Panel_3, 'text');
            app.timeBeforeFV.Tag = 'timeBeforeFV';
            app.timeBeforeFV.Value = '1';
            app.drtaThWhatDisplay = uidropdown(app.Panel_3);
            app.drtaThWhatDisplay.Items = {'Pop-up Menu'};
            app.drtaThWhatDisplay.ValueChangedFcn = createCallbackFcn(app, @drtaThWhatDisplay_Callback, true);
            app.drtaThWhatDisplay.Tag = 'drtaThWhatDisplay';
            app.drtaThWhatDisplay.Value = 'Pop-up Menu';
            app.drtaThrSaveMat = uibutton(app.Panel_3, 'push');
            app.drtaThrSaveMat.ButtonPushedFcn = createCallbackFcn(app, @drtaThrSaveMat_Callback, true);
            app.drtaThrSaveMat.Tag = 'drtaThrSaveMat';
            app.drtaThrSaveMat.Text = 'Save .mat';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = drtaThresholdSnips(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)drtaThresholdSnips_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end